classdef prueba6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes2                    matlab.ui.control.UIAxes
        ElmovimientoimaginadofueEditFieldLabel  matlab.ui.control.Label
        ElmovimientoimaginadofueEditField  matlab.ui.control.EditField
        Panel                      matlab.ui.container.Panel
        Movimiento3Button          matlab.ui.control.Button
        Movimiento4Button          matlab.ui.control.Button
        SujetoDropDownLabel        matlab.ui.control.Label
        SujetoDropDown             matlab.ui.control.DropDown
        Movimiento1Button          matlab.ui.control.Button
        Movimiento2Button          matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        TodosMovimientosButton     matlab.ui.control.Button
        Paracadaunodelosmovimientossetienen72repeticionesLabel  matlab.ui.control.Label
        Lamp                       matlab.ui.control.Lamp
        Lamp_2                     matlab.ui.control.Lamp
        Lamp_3                     matlab.ui.control.Lamp
        Lamp_4                     matlab.ui.control.Lamp
        Lamp_5                     matlab.ui.control.Lamp
        LinearDiscriminantAnalysisPanel  matlab.ui.container.Panel
        PrecisionLDA               matlab.ui.control.NumericEditField
        EspecificidadLDA           matlab.ui.control.NumericEditField
        SensibilidadLDA            matlab.ui.control.NumericEditField
        KappaLDA                   matlab.ui.control.NumericEditField
        PrecisionLabel_2           matlab.ui.control.Label
        EspecificidadLabel_2       matlab.ui.control.Label
        SensibilidadLabel_2        matlab.ui.control.Label
        KappaLabel_2               matlab.ui.control.Label
        Panel_3                    matlab.ui.container.Panel
        SupportMachineVectorPanel  matlab.ui.container.Panel
        PrecisionSVM               matlab.ui.control.NumericEditField
        EspecificidadSVM           matlab.ui.control.NumericEditField
        PrecisionLabel             matlab.ui.control.Label
        EspecificidadLabel         matlab.ui.control.Label
        SensibilidadLabel          matlab.ui.control.Label
        SensibilidadSVM            matlab.ui.control.NumericEditField
        KappaLabel                 matlab.ui.control.Label
        KappaSVM                   matlab.ui.control.NumericEditField
        Panel_2                    matlab.ui.container.Panel
        YapuedevisualizarLabel     matlab.ui.control.Label
        PromediosdetodoslosmovimientosLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global file_paths
            global D_Filt1
            global Accuracy
            global TNRT
            global TPRT
            global Kappa_svm
            global Accuracy2
            global TNRT_2
            global TPRT_2
            global Kappa_svm_2
            global Accuracy3
            global TNRT_3
            global TPRT_3
            global Kappa_svm_3
            global Accuracy4
            global TNRT_4
            global TPRT_4
            global Kappa_svm_4
            global AccuracyLDA
            global TNRTLDA
            global TPRTLDA
            global Kappa_lda
            global AccuracyLDA2
            global TPRTLDA_2
            global TNRTLDA_2
            global Kappa_lda_2
            global AccuracyLDA3
            global TNRTLDA_3
            global TPRTLDA_3
            global Kappa_lda_3
            global AccuracyLDA4
            global TNRTLDA_4
            global TPRTLDA_4
            global Kappa_lda_4 
            global labels
            global TotalsumSVM
            global TotalsumSVM_2
            global TotalsumSVM_3
            global TotalsumSVM_4
            global TotalsumLDA
            global TotalsumLDA_2
            global TotalsumLDA_3
            global TotalsumLDA_4
            global Mean1
            global Median1
            global Mode1
            global Stan_dev1
            global Max1
            global Min1
            global Mean2
            global Median2
            global Mode2
            global Stan_dev2
            global Max2
            global Min2
            global Mean3
            global Median3
            global Mode3
            global Stan_dev3
            global Max3
            global Min3
            global Mean4
            global Median4
            global Mode4
            global Stan_dev4
            global Max4
            global Min4
            global A
            global pos_l
            global pos_r
            global pos_t
            global pos_f          
            global p1
            global p2
            global p3
            global p4
            global TestL
            global predictSVM
            global CSVM2
            global CSVM3
            global CSVM4
            global CLDA
            global CLDA2
            global CLDA3
            global CLDA4
            global CSVM

input_folder = 'D:\Documentos N\Tesis\BCICIV_2a_gdf'; %Nombre de la carpeta de donde se van a obtener los documentos
files = dir(fullfile(input_folder, '*.gdf')); % lectura de todos los documentos con una especifica extension, en nuestro caso gdf
file_paths = fullfile({files.folder}, {files.name});% it will specify images names with full path and extension

D_Filt1 = designfilt('bandpassfir','FilterOrder',1024, ....
    'StopbandFrequency1',(7),'PassbandFrequency1',(8), ...
    'PassbandFrequency2',(40),'StopbandFrequency2',(41), ...
    'SampleRate',250);

im=imread('silueta3.jpg');
imshow((im),'Parent',app.UIAxes2);

%Matriz de etiquetas
Label1(1:72,1)=1;
Label2(1:216,1)=2;
labels=[Label1;Label2];

Accuracy(1:9)=0;TPRT(1:9)=0;TNRT(1:9)=0;Kappa_svm(1:9)=0;
TotalsumSVM(1:9)=0;
Accuracy2(1:9)=0;TPRT_2(1:9)=0;TNRT_2(1:9)=0;Kappa_svm_2(1:9)=0;
TotalsumSVM_2(1:9)=0;
Accuracy3(1:9)=0;TPRT_3(1:9)=0;TNRT_3(1:9)=0;Kappa_svm_3(1:9)=0;
TotalsumSVM_3(1:9)=0;
Accuracy4(1:9)=0;TPRT_4(1:9)=0;TNRT_4(1:9)=0;Kappa_svm_4(1:9)=0;
TotalsumSVM_4(1:9)=0;

AccuracyLDA(1:9)=0;TPRTLDA(1:9)=0;TNRTLDA(1:9)=0;Kappa_lda(1:9)=0;
TotalsumLDA(1:9)=0;
AccuracyLDA2(1:9)=0;TPRTLDA_2(1:9)=0;TNRTLDA_2(1:9)=0;Kappa_lda_2(1:9)=0;
TotalsumLDA_2(1:9)=0;
AccuracyLDA3(1:9)=0;TPRTLDA_3(1:9)=0;TNRTLDA_3(1:9)=0;Kappa_lda_3(1:9)=0;
TotalsumLDA_3(1:9)=0;
AccuracyLDA4(1:9)=0;TPRTLDA_4(1:9)=0;TNRTLDA_4(1:9)=0;Kappa_lda_4(1:9)=0;
TotalsumLDA_4(1:9)=0;

Mean1(1:72,1:87)=0;Median1(1:72,1:87)=0;Mode1(1:72,1:87)=0;
Stan_dev1(1:72,1:87)=0;Max1(1:72,1:87)=0;Min1(1:72,1:87)=0;

Mean2(1:72,1:87)=0;Median2(1:72,1:87)=0;Mode2(1:72,1:87)=0;
Stan_dev2(1:72,1:87)=0;Max2(1:72,1:87)=0;Min2(1:72,1:87)=0;

Mean3(1:72,1:87)=0;Median3(1:72,1:87)=0;Mode3(1:72,1:87)=0;
Stan_dev3(1:72,1:87)=0;Max3(1:72,1:87)=0;Min3(1:72,1:87)=0;

Mean4(1:72,1:87)=0;Median4(1:72,1:87)=0;Mode4(1:72,1:87)=0;
Stan_dev4(1:72,1:87)=0;Max4(1:72,1:87)=0;Min4(1:72,1:87)=0;

                  
for i = 1 : 9
        
    [SIG,HEAD] = sload(file_paths{i}, '');
     SIG(isnan(SIG))=0;
     SIGEEG=SIG(:,1:22);
     A= filtfilt(D_Filt1,SIGEEG);
     
     typ_l = HEAD.EVENT.TYP==769;%Encontrar el movimiento de mano derecha (769)en la matriz head.event.typ
     pos_l=(HEAD.EVENT.POS(typ_l)); %
     typ_r = HEAD.EVENT.TYP==770;
     pos_r = (HEAD.EVENT.POS(typ_r));
     typ_f = HEAD.EVENT.TYP==771;
     pos_f = (HEAD.EVENT.POS(typ_f));
     typ_t = HEAD.EVENT.TYP==772;
     pos_t = (HEAD.EVENT.POS(typ_t));  
     
parfor j=1:72
   p1=(A(pos_l(j):pos_l(j)+1252,(1:22)));  
   p2=(A(pos_r(j):pos_r(j)+1252,(1:22)));
   p3=(A(pos_f(j):pos_f(j)+1252,(1:22)));
   p4=(A(pos_t(j):pos_t(j)+1252,(1:22)));
   p5=[p1(:,2:22),p2,p3,p4];
   p6=[p2(:,2:22),p1,p3,p4];
   p7=[p3(:,2:22),p1,p2,p4];
   p8=[p4(:,2:22),p1,p2,p3]; 

    cross1=fliplr(xcorr2(p1(:,1),p5(:,:)));
    cross2=fliplr(xcorr2(p2(:,1),p6(:,:)));
    cross3=fliplr(xcorr2(p3(:,1),p7(:,:)));
    cross4=fliplr(xcorr2(p4(:,1),p8(:,:)));
    
    mode_1=sort(cross1);mode_1=mode_1(1,:);Mode1(j,:)=mode_1;
    Mean1(j,:)=mean(cross1);Median1(j,:)=median(cross1);
    Stan_dev1(j,:)=std(cross1);Max1(j,:)=max(cross1);Min1(j,:)=min(cross1);
    
    mode_2=sort(cross2);mode_2=mode_2(1,:);Mode2(j,:)=mode_2;
    Mean2(j,:)=mean(cross2);Median2(j,:)=median(cross2);
    Stan_dev2(j,:)=std(cross2);Max2(j,:)=max(cross2);Min2(j,:)=min(cross2);
    
    mode_3=sort(cross3);mode_3=mode_3(1,:);Mode3(j,:)=mode_3;
    Mean3(j,:)=mean(cross3);Median3(j,:)=median(cross3);
    Stan_dev3(j,:)=std(cross3);Max3(j,:)=max(cross3);Min3(j,:)=min(cross3);
    
    mode_4=sort(cross4);mode_4=mode_4(1,:);Mode4(j,:)=mode_4;
    Mean4(j,:)=mean(cross4);Median4(j,:)=median(cross4);
    Stan_dev4(j,:)=std(cross4);Max4(j,:)=max(cross4);Min4(j,:)=min(cross4);
end

features1=[Mean1(:,1:21),Median1(:,1:21),Mode1(:,1:21),Stan_dev1(:,1:21),...
    Max1(:,1:21),Min1(:,1:21),Median1(:,5:10);Mean1(:,22:43),Median1(:,22:43),Mode1(:,22:43),Stan_dev1(:,22:43),...
    Max1(:,22:43),Min1(:,22:43);Mean1(:,44:65),Median1(:,44:65),Mode1(:,44:65),Stan_dev1(:,44:65),...
    Max1(:,44:65),Min1(:,44:65);Mean1(:,66:87),Median1(:,66:87),Mode1(:,66:87),Stan_dev1(:,66:87),...
    Max1(:,66:87),Min1(:,66:87)];
features2=[Mean2(:,1:21),Median2(:,1:21),Mode2(:,1:21),Stan_dev2(:,1:21),...
    Max2(:,1:21),Min2(:,1:21),Median2(:,5:10);Mean2(:,22:43),Median2(:,22:43),Mode2(:,22:43),Stan_dev2(:,22:43),...
    Max2(:,22:43),Min2(:,22:43);Mean2(:,44:65),Median2(:,44:65),Mode2(:,44:65),Stan_dev2(:,44:65),...
    Max2(:,44:65),Min2(:,44:65);Mean2(:,66:87),Median2(:,66:87),Mode2(:,66:87),Stan_dev2(:,66:87),...
    Max2(:,66:87),Min2(:,66:87)];
features3=[Mean3(:,1:21),Median3(:,1:21),Mode3(:,1:21),Stan_dev3(:,1:21),...
    Max3(:,1:21),Min3(:,1:21),Median3(:,5:10);Mean3(:,22:43),Median3(:,22:43),Mode3(:,22:43),Stan_dev3(:,22:43),...
    Max3(:,22:43),Min3(:,22:43);Mean3(:,44:65),Median3(:,44:65),Mode3(:,44:65),Stan_dev3(:,44:65),...
    Max3(:,44:65),Min3(:,44:65);Mean3(:,66:87),Median3(:,66:87),Mode3(:,66:87),Stan_dev3(:,66:87),...
    Max3(:,66:87),Min3(:,66:87)];
features4=[Mean4(:,1:21),Median4(:,1:21),Mode4(:,1:21),Stan_dev4(:,1:21),...
    Max4(:,1:21),Min4(:,1:21),Median4(:,5:10);Mean4(:,22:43),Median4(:,22:43),Mode4(:,22:43),Stan_dev4(:,22:43),...
    Max4(:,22:43),Min4(:,22:43);Mean4(:,44:65),Median4(:,44:65),Mode4(:,44:65),Stan_dev4(:,44:65),...
    Max4(:,44:65),Min4(:,44:65);Mean4(:,66:87),Median4(:,66:87),Mode4(:,66:87),Stan_dev4(:,66:87),...
    Max4(:,66:87),Min4(:,66:87)];

% Cross validation (train: 70%, test: 30%) CSP
cvy = cvpartition(labels,'Holdout',0.3);
Parititon = cvy.test;
% Separate to training and test data
% %Train data
TrainF1 = features1(~Parititon,:);
TrainF2 = features2(~Parititon,:);
TrainF3 = features3(~Parititon,:);
TrainF4 = features4(~Parititon,:);
TrainL = labels(~Parititon,:);
%test data
TestF1 = features1(Parititon,:);
TestF2 = features2(Parititon,:);
TestF3 = features3(Parititon,:);
TestF4 = features4(Parititon,:);
TestL  =labels(Parititon,:);
% SVM Clase A vs todas
template = templateSVM('KernelFunction', 'gaussian', 'PolynomialOrder', [], 'KernelScale', 'auto', ...
    'BoxConstraint', 1, 'Standardize', true);
classificationSVM = fitcecoc (TrainF1,TrainL,'Learners', template, 'Coding', 'onevsall', ...
    'ClassNames', [1;2]);
CVSVM = crossval(classificationSVM,'kfold',10); % validación cruzada
genError2 = kfoldLoss(CVSVM); % Error de generlización
%Ones vs all prediccion
predictSVM = predict(classificationSVM,TestF1);
%Confusion matrix generation
CSVM(:,:,i)= confusionmat(TestL,predictSVM);
Accuracy(i)=(1-genError2)*100;
%sensibilidad
TPRT(i)=(CSVM(1,1,i)/(CSVM(1,1,i)+CSVM(1,2,i)))*100;
%especificidad
TNRT(i)=(CSVM(2,2,i)/(CSVM(2,2,i)+CSVM(2,1,i)))*100;
%Kappa
N = length(TestL);
CM_svm=(CSVM(1,1,i)+CSVM(2,2,i));
C1corr_svm=sum(CSVM(1,:,i));
C2corr_svm=sum(CSVM(2,:,i));
C1pre_svm=sum(CSVM(:,1,i));
C2pre_svm=sum(CSVM(:,2,i));
TotalsumSVM(i)=(C1corr_svm*C1pre_svm+C2corr_svm*C2pre_svm);
Kappa_svm(i)=(N*CM_svm-(TotalsumSVM(i)))/(N^2-TotalsumSVM(i));

% SVM Clase B vs todas
template2 = templateSVM('KernelFunction', 'gaussian', 'PolynomialOrder', [], 'KernelScale', 'auto', ...
    'BoxConstraint', 1, 'Standardize', true);
classificationSVM2 = fitcecoc (TrainF2,TrainL,'Learners', template2, 'Coding', 'onevsall', ...
    'ClassNames', [1;2]);
CVSVM2 = crossval(classificationSVM2,'kfold',10); % validación cruzada
genError22 = kfoldLoss(CVSVM2); % Error de generlización
%Ones vs all prediccion
predictSVM2 = predict(classificationSVM2,TestF2);
%Confusion matrix generation
CSVM2(:,:,i)= confusionmat(TestL,predictSVM2);
Accuracy2(i)=(1-genError22)*100;
%sensibilidad
TPRT_2(i)=(CSVM2(1,1,i)/(CSVM2(1,1,i)+CSVM2(1,2,i)))*100;
%especificidad
TNRT_2(i)=(CSVM2(2,2,i)/(CSVM2(2,2,i)+CSVM2(2,1,i)))*100;
%Kappa
CM_svm_2=(CSVM2(1,1,i)+CSVM2(2,2,i));
C1corr_svm_2=sum(CSVM2(1,:,i));
C2corr_svm_2=sum(CSVM2(2,:,i));
C1pre_svm_2=sum(CSVM2(:,1,i));
C2pre_svm_2=sum(CSVM2(:,2,i));
TotalsumSVM_2(i)=(C1corr_svm_2*C1pre_svm_2+C2corr_svm_2*C2pre_svm_2);
Kappa_svm_2(i)=(N*CM_svm_2-(TotalsumSVM_2(i)))/(N^2-TotalsumSVM_2(i));

% SVM Clase C vs todas
template3 = templateSVM('KernelFunction', 'gaussian', 'PolynomialOrder', [], 'KernelScale', 'auto', ...
    'BoxConstraint', 1, 'Standardize', true);
classificationSVM3 = fitcecoc (TrainF3,TrainL,'Learners', template3, 'Coding', 'onevsall', ...
    'ClassNames', [1;2]);
CVSVM3 = crossval(classificationSVM3,'kfold',10); % validación cruzada
genError23 = kfoldLoss(CVSVM3); % Error de generlización
%Ones vs all prediccion
predictSVM3 = predict(classificationSVM3,TestF3);
%Confusion matrix generation
CSVM3(:,:,i)= confusionmat(TestL,predictSVM3);
Accuracy3(i)=(1-genError23)*100;
%sensibilidad
TPRT_3(i)=(CSVM3(1,1,i)/(CSVM3(1,1,i)+CSVM3(1,2,i)))*100;
%especificidad
TNRT_3(i)=(CSVM3(2,2,i)/(CSVM3(2,2,i)+CSVM3(2,1,i)))*100;
%Kappa
CM_svm_3=(CSVM3(1,1,i)+CSVM3(2,2,i));
C1corr_svm_3=sum(CSVM3(1,:,i));
C2corr_svm_3=sum(CSVM3(2,:,i));
C1pre_svm_3=sum(CSVM3(:,1,i));
C2pre_svm_3=sum(CSVM3(:,2,i));
TotalsumSVM_3(i)=(C1corr_svm_3*C1pre_svm_3+C2corr_svm_3*C2pre_svm_3);
Kappa_svm_3(i)=(N*CM_svm_3-(TotalsumSVM_3(i)))/(N^2-TotalsumSVM_3(i));

% SVM Clase D vs todas
template4 = templateSVM('KernelFunction', 'gaussian', 'PolynomialOrder', [], 'KernelScale', 'auto', ...
    'BoxConstraint', 1, 'Standardize', true);
classificationSVM4 = fitcecoc (TrainF4,TrainL,'Learners', template4, 'Coding', 'onevsone', ...
    'ClassNames', [1;2]);
CVSVM4 = crossval(classificationSVM4,'kfold',10); % validación cruzada
genError24 = kfoldLoss(CVSVM4); % Error de generlización
%Ones vs all prediccion
predictSVM4 = predict(classificationSVM4,TestF4);
%Confusion matrix generation
CSVM4(:,:,i)= confusionmat(TestL,predictSVM4);
Accuracy4(i)=(1-genError24)*100;
%sensibilidad
TPRT_4(i)=(CSVM4(1,1,i)/(CSVM4(1,1,i)+CSVM(1,2,i)))*100;
% %especificidad
TNRT_4(i)=(CSVM4(2,2,i)/(CSVM4(2,2,i)+CSVM4(2,1,i)))*100;
%Kappa
CM_svm_4=(CSVM4(1,1,i)+CSVM4(2,2,i));
C1corr_svm_4=sum(CSVM4(1,:,i));
C2corr_svm_4=sum(CSVM4(2,:,i));
C1pre_svm_4=sum(CSVM4(:,1,i));
C2pre_svm_4=sum(CSVM4(:,2,i));
TotalsumSVM_4(i)=(C1corr_svm_4*C1pre_svm_4+C2corr_svm_4*C2pre_svm_4);
Kappa_svm_4(i)=(N*CM_svm_4-(TotalsumSVM_4(i)))/(N^2-TotalsumSVM_4(i));

%LDA A versus todas
ClassificationLDA = fitcdiscr(TrainF1,TrainL,'DiscrimType', 'linear', 'Gamma', 0,...
    'FillCoeffs', 'off','ClassNames', [1; 2]);
ResultLDA = predict(ClassificationLDA,TestF1);
CVLDA = crossval(ClassificationLDA);
genError1 = kfoldLoss(CVLDA);
AccuracyLDA(i)=(1-genError1)*100;
CLDA(:,:,i)= confusionmat(TestL,ResultLDA);
%sensibilidad
TPRTLDA(i)=(CLDA(1,1,i)/(CLDA(1,1,i)+CLDA(1,2,i)))*100;
% %especificidad
TNRTLDA(i)=(CLDA(2,2,i)/(CLDA(2,2,i)+CLDA(2,1,i)))*100;
%Kappa
CM_lda=(CLDA(1,1,i)+CLDA(2,2,i));
C1corr_lda=sum(CLDA(1,:,i));
C2corr_lda=sum(CLDA(2,:,i));
C1pre_lda=sum(CLDA(:,1,i));
C2pre_lda=sum(CLDA(:,2,i));
TotalsumLDA(i)=(C1corr_lda*C1pre_lda+C2corr_lda*C2pre_lda);
Kappa_lda(i)=(N*CM_lda-(TotalsumLDA(i)))/(N^2-TotalsumLDA(i));

%LDA b versus todas
ClassificationLDA2 = fitcdiscr(TrainF2,TrainL,'DiscrimType', 'linear', 'Gamma', 0,...
    'FillCoeffs', 'off','ClassNames', [1; 2]);
ResultLDA2 = predict(ClassificationLDA2,TestF2);
CVLDA2 = crossval(ClassificationLDA2);
genError12 = kfoldLoss(CVLDA2);
AccuracyLDA2(i)=(1-genError12)*100;
CLDA2(:,:,i)= confusionmat(TestL,ResultLDA2);
%sensibilidad
TPRTLDA_2(i)=(CLDA2(1,1,i)/(CLDA(1,1,i)+CLDA(1,2,i)))*100;
% % %especificidad
TNRTLDA_2(i)=(CLDA2(2,2,i)/(CLDA2(2,2,i)+CLDA2(2,1,i)))*100;
%Kappa
CM_lda_2=(CLDA2(1,1,i)+CLDA2(2,2,i));
C1corr_lda_2=sum(CLDA2(1,:,i));
C2corr_lda_2=sum(CLDA2(2,:,i));
C1pre_lda_2=sum(CLDA2(:,1,i));
C2pre_lda_2=sum(CLDA2(:,2,i));
TotalsumLDA_2(i)=(C1corr_lda_2*C1pre_lda_2+C2corr_lda_2*C2pre_lda_2);
Kappa_lda_2(i)=(N*CM_lda_2-(TotalsumLDA_2(i)))/(N^2-TotalsumLDA_2(i));

%LDA C versus todas
ClassificationLDA3 = fitcdiscr(TrainF3,TrainL,'DiscrimType', 'linear', 'Gamma', 0,...
    'FillCoeffs', 'off','ClassNames', [1; 2]);
ResultLDA3 = predict(ClassificationLDA3,TestF3);
CVLDA3 = crossval(ClassificationLDA3);
genError13 = kfoldLoss(CVLDA3);
CLDA3(:,:,i)=confusionmat(TestL,ResultLDA3);
AccuracyLDA3(i)=(1-genError13)*100;
%sensibilidad
TPRTLDA_3(i)=(CLDA3(1,1,i)/(CLDA3(1,1,i)+CLDA3(1,2,i)))*100;
% % %especificidad
TNRTLDA_3(i)=(CLDA3(2,2,i)/(CLDA3(2,2,i)+CLDA3(2,1,i)))*100;
%Kappa
CM_lda_3=(CLDA3(1,1,i)+CLDA3(2,2,i));
C1corr_lda_3=sum(CLDA3(1,:,i));
C2corr_lda_3=sum(CLDA3(2,:,i));
C1pre_lda_3=sum(CLDA3(:,1,i));
C2pre_lda_3=sum(CLDA3(:,2,i));
TotalsumLDA_3(i)=(C1corr_lda_3*C1pre_lda_3+C2corr_lda_3*C2pre_lda_3);
Kappa_lda_3(i)=(N*CM_lda_3-(TotalsumLDA_3(i)))/(N^2-TotalsumLDA_3(i));

%LDA D versus todas
ClassificationLDA4 = fitcdiscr(TrainF4,TrainL,'DiscrimType', 'linear', 'Gamma', 0,...
    'FillCoeffs', 'off','ClassNames', [1; 2]);
ResultLDA4 = predict(ClassificationLDA4,TestF4);
CVLDA4 = crossval(ClassificationLDA4);
genError14 = kfoldLoss(CVLDA4);
AccuracyLDA4(i)=(1-genError14)*100;
CLDA4(:,:,i)= confusionmat(TestL,ResultLDA4);
%sensibilidad
TPRTLDA_4(i)=(CLDA4(1,1,i)/(CLDA4(1,1,i)+CLDA4(1,2,i)))*100;
% % %especificidad
TNRTLDA_4(i)=(CLDA4(2,2,i)/(CLDA4(2,2,i)+CLDA4(2,1,i)))*100;
%Kappa
CM_lda_4=(CLDA4(1,1,i)+CLDA4(2,2,i));
C1corr_lda_4=sum(CLDA4(1,:,i));
C2corr_lda_4=sum(CLDA4(2,:,i));
C1pre_lda_4=sum(CLDA4(:,1,i));
C2pre_lda_4=sum(CLDA4(:,2,i));
TotalsumLDA_4(i)=(C1corr_lda_4*C1pre_lda_4+C2corr_lda_4*C2pre_lda_4);
Kappa_lda_4(i)=(N*CM_lda_4-(TotalsumLDA_4(i)))/(N^2-TotalsumLDA_4(i));
end  

        end

        % Value changed function: SujetoDropDown
        function IniciarButtonPushed(app, event)
          global suj
          global p1
          global p2
          global p3
          global p4
          global A
          global file_paths
          global suj2
          
app.PromediosdetodoslosmovimientosLabel.Visible='off';
app.YapuedevisualizarLabel.Visible='off';
app.PromediosdetodoslosmovimientosLabel.Visible='off';
app.PrecisionSVM.Visible='off';
app.EspecificidadSVM.Visible='off';
app.SensibilidadSVM.Visible='off';
app.KappaSVM.Visible='off';
app.PrecisionLDA.Visible='off';
app.EspecificidadLDA.Visible='off';
app.SensibilidadLDA.Visible='off';
app.KappaLDA.Visible='off';
app.Panel_2.Visible='off';
app.Panel_3.Visible='off';
app.ElmovimientoimaginadofueEditFieldLabel.Visible='off';
app.ElmovimientoimaginadofueEditField.Visible='off';


suj = app.SujetoDropDown.Value;
suj2 = str2double(suj);
            
        if isequal (suj,'Todos') 
            for i = 5 
                [SIG,HEAD] = sload(file_paths{i}, '');
                 SIG(isnan(SIG))=0;
                 SIGEEG=SIG(:,1:22);
                 A = (SIGEEG);
                 
                 typ_l = HEAD.EVENT.TYP==769;%Encontrar el movimiento de mano derecha (769)en la matriz head.event.typ
                 pos_l=(HEAD.EVENT.POS(typ_l)); %
                 typ_r = HEAD.EVENT.TYP==770;
                 pos_r = (HEAD.EVENT.POS(typ_r));
                 typ_f = HEAD.EVENT.TYP==771;
                 pos_f = (HEAD.EVENT.POS(typ_f));
                 typ_t = HEAD.EVENT.TYP==772;
                 pos_t = (HEAD.EVENT.POS(typ_t));  
                 
            for j=1:72
               p1=(A(pos_l(j):pos_l(j)+1252,(1:22)));  
               p2=(A(pos_r(j):pos_r(j)+1252,(1:22)));
               p3=(A(pos_f(j):pos_f(j)+1252,(1:22)));
               p4=(A(pos_t(j):pos_t(j)+1252,(1:22)));
            end 
            end
                Dx=200;
                y1=min(p2(:,1));
                y2=max(p2(:,22)+22*80);
                for t=1:200:1052
                for k=1:22
                plot(app.UIAxes3,p2(:,k)+k*80)
                hold(app.UIAxes3,"on")
                str2 = [' Señales EEG - Movimiento imaginado mano izquierda'];
                title(app.UIAxes3,str2)
                end
                app.UIAxes3.XLim=[t t+Dx];
                app.UIAxes3.YLim=[y1 y2];
                
                drawnow;
                end
                hold(app.UIAxes3,"off")
        else 
            for i = suj2 
                [SIG,HEAD] = sload(file_paths{i}, '');
                 SIG(isnan(SIG))=0;
                 SIGEEG=SIG(:,1:22);
                 A = (SIGEEG);
                 
                 typ_l = HEAD.EVENT.TYP==769;%Encontrar el movimiento de mano derecha (769)en la matriz head.event.typ
                 pos_l=(HEAD.EVENT.POS(typ_l)); %
                 typ_r = HEAD.EVENT.TYP==770;
                 pos_r = (HEAD.EVENT.POS(typ_r));
                 typ_f = HEAD.EVENT.TYP==771;
                 pos_f = (HEAD.EVENT.POS(typ_f));
                 typ_t = HEAD.EVENT.TYP==772;
                 pos_t = (HEAD.EVENT.POS(typ_t));  
                 
            for j=1:72
               p1=(A(pos_l(j):pos_l(j)+1252,(1:22)));  
               p2=(A(pos_r(j):pos_r(j)+1252,(1:22)));
               p3=(A(pos_f(j):pos_f(j)+1252,(1:22)));
               p4=(A(pos_t(j):pos_t(j)+1252,(1:22)));
            end 
            end
                Dx=200;
                y1=min(p1(:,1));
                y2=max(p1(:,22)+22*80);
                for t=1:200:1052
                for k=1:22
                plot(app.UIAxes3,p1(:,k)+k*80)
                hold(app.UIAxes3,"on")
                end
                app.UIAxes3.XLim=[t t+Dx];
                app.UIAxes3.YLim=[y1 y2];
                str2 = ['Paciente ',num2str(suj2), ' Señales EEG - Movimiento imaginado mano izquierda'];
                title(app.UIAxes3,str2)
                drawnow;
                end
                hold(app.UIAxes3,"off")
        end 
        
app.YapuedevisualizarLabel.Visible='on';

        end

        % Callback function: EspecificidadLDA, EspecificidadSVM, 
        % KappaLDA, KappaSVM, Movimiento1Button, PrecisionLDA, 
        % PrecisionSVM, SensibilidadLDA, SensibilidadSVM
        function Movimiento1ButtonPushed(app, event)
global Accuracy
global TNRT
global TPRT
global Kappa_svm
global AccuracyLDA
global TNRTLDA
global TPRTLDA
global Kappa_lda
global p1
global CSVM
global suj2
global CLDA

%Apagar 
app.PromediosdetodoslosmovimientosLabel.Visible='off';
app.PrecisionSVM.Visible='off';
app.EspecificidadSVM.Visible='off';
app.SensibilidadSVM.Visible='off';
app.KappaSVM.Visible='off';
app.PrecisionLDA.Visible='off';
app.EspecificidadLDA.Visible='off';
app.SensibilidadLDA.Visible='off';
app.KappaLDA.Visible='off';
app.Panel_2.Visible='off';
app.Panel_3.Visible='off';
app.ElmovimientoimaginadofueEditFieldLabel.Visible='off';
app.ElmovimientoimaginadofueEditField.Visible='off';

%lamparas apagadas
app.Lamp_2.Color='w';
app.Lamp_3.Color='w';
app.Lamp_4.Color='w';
app.Lamp_5.Color='w';

Dx=200;
y1=min(p1(:,1));
y2=max(p1(:,22)+22*80);
for t=1:200:1052
for k=1:22
plot(app.UIAxes3,p1(:,k)+k*80)
hold(app.UIAxes3,"on")
end
app.UIAxes3.XLim=[t t+Dx];
app.UIAxes3.YLim=[y1 y2];
str2 = ['Paciente ',num2str(suj2), ' Señales EEG - Movimiento imaginado mano izquierda'];
title(app.UIAxes3,str2)
drawnow;
end
hold(app.UIAxes3,"off")

app.Panel_2.Visible='on';
app.Panel_3.Visible='on';
app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = Accuracy(suj2);
app.EspecificidadSVM.Value = TNRT(suj2);
app.SensibilidadSVM.Value = TPRT(suj2);
app.KappaSVM.Value = Kappa_svm(suj2);
app.Lamp.Color='g';
app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = AccuracyLDA(suj2);
app.EspecificidadLDA.Value = TNRTLDA(suj2);
app.SensibilidadLDA.Value = TPRTLDA(suj2);
app.KappaLDA.Value = Kappa_lda(suj2);
app.Lamp.Color='g';
app.ElmovimientoimaginadofueEditFieldLabel.Visible='on';
app.ElmovimientoimaginadofueEditField.Visible='on';
app.ElmovimientoimaginadofueEditField.Value="Mano izquierda";


heatmap(app.Panel_2,CSVM(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión SVM');
heatmap(app.Panel_3,CLDA(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión LDA');

        end

        % Button pushed function: Movimiento2Button
        function Movimiento2ButtonPushed(app, event)
            global p2
            global Accuracy2
            global TNRT_2
            global TPRT_2
            global Kappa_svm_2
            global AccuracyLDA2
            global TNRTLDA_2
            global TPRTLDA_2
            global Kappa_lda_2
            global CSVM2
            global CLDA2
            global suj2
            
%Apagar 
app.PromediosdetodoslosmovimientosLabel.Visible='off';
app.PrecisionSVM.Visible='off';
app.EspecificidadSVM.Visible='off';
app.SensibilidadSVM.Visible='off';
app.KappaSVM.Visible='off';
app.PrecisionLDA.Visible='off';
app.EspecificidadLDA.Visible='off';
app.SensibilidadLDA.Visible='off';
app.KappaLDA.Visible='off';
app.Panel_2.Visible='off';
app.Panel_3.Visible='off';

app.ElmovimientoimaginadofueEditFieldLabel.Visible='off';
app.ElmovimientoimaginadofueEditField.Visible='off';

%lamparas apagadas
app.Lamp.Color='w';
app.Lamp_3.Color='w';
app.Lamp_4.Color='w';
app.Lamp_5.Color='w';

%inicio codigo
Dx=200;
y1=min(p2(:,1));
y2=max(p2(:,22)+22*80);
for t=1:200:1052
for k=1:22
plot(app.UIAxes3,p2(:,k)+k*80)
hold(app.UIAxes3,"on")
end
app.UIAxes3.XLim=[t t+Dx];
app.UIAxes3.YLim=[y1 y2];
str2 = ['Paciente ',num2str(suj2), ' Señales EEG - Movimiento imaginado mano derecha'];
title(app.UIAxes3,str2)
drawnow;
end
hold(app.UIAxes3,"off")

app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = Accuracy2(suj2);
app.EspecificidadSVM.Value = TNRT_2(suj2);
app.SensibilidadSVM.Value = TPRT_2(suj2);
app.KappaSVM.Value = Kappa_svm_2(suj2);

app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = AccuracyLDA2(suj2);
app.EspecificidadLDA.Value = TNRTLDA_2(suj2);
app.SensibilidadLDA.Value = TPRTLDA_2(suj2);
app.KappaLDA.Value = Kappa_lda_2(suj2);

app.Lamp_2.Color='g';
app.ElmovimientoimaginadofueEditFieldLabel.Visible='on';
app.ElmovimientoimaginadofueEditField.Visible='on';
app.ElmovimientoimaginadofueEditField.Value="Mano derecha";


app.Panel_2.Visible='on';
app.Panel_3.Visible='on';
heatmap(app.Panel_2,CSVM2(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión SVM');
heatmap(app.Panel_3,CLDA2(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión LDA');
        end

        % Button pushed function: Movimiento3Button
        function Movimiento3ButtonPushed(app, event)
            global Accuracy3
            global TNRT_3
            global TPRT_3
            global Kappa_svm_3
            global AccuracyLDA3
            global TNRTLDA_3
            global TPRTLDA_3
            global Kappa_lda_3
            global p3 
            global CSVM3
            global CLDA3
            global suj2
            
%Apagar 
app.PromediosdetodoslosmovimientosLabel.Visible='off';
app.PrecisionSVM.Visible='off';
app.EspecificidadSVM.Visible='off';
app.SensibilidadSVM.Visible='off';
app.KappaSVM.Visible='off';
app.PrecisionLDA.Visible='off';
app.EspecificidadLDA.Visible='off';
app.SensibilidadLDA.Visible='off';
app.KappaLDA.Visible='off';
app.Panel_2.Visible='off';
app.Panel_3.Visible='off';

app.ElmovimientoimaginadofueEditFieldLabel.Visible='off';
app.ElmovimientoimaginadofueEditField.Visible='off';

%lamparas apagadas
app.Lamp.Color='w';
app.Lamp_2.Color='w';
app.Lamp_5.Color='w';

%inicio codigo
Dx=200;
y1=min(p3(:,1));
y2=max(p3(:,22)+22*80);
for t=1:200:1052
for k=1:22
plot(app.UIAxes3,p3(:,k)+k*80)
hold(app.UIAxes3,"on")
end
app.UIAxes3.XLim=[t t+Dx];
app.UIAxes3.YLim=[y1 y2];
str2 = ['Paciente ',num2str(suj2), ' Señales EEG - Movimiento imaginado pies'];
title(app.UIAxes3,str2)
drawnow;
end
hold(app.UIAxes3,"off")

app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = Accuracy3(suj2);
app.EspecificidadSVM.Value = TNRT_3(suj2);
app.SensibilidadSVM.Value = TPRT_3(suj2);
app.KappaSVM.Value = Kappa_svm_3(suj2);

app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = AccuracyLDA3(suj2);
app.EspecificidadLDA.Value = TNRTLDA_3(suj2);
app.SensibilidadLDA.Value = TPRTLDA_3(suj2);
app.KappaLDA.Value = Kappa_lda_3(suj2);

app.Lamp_3.Color='g';
app.Lamp_4.Color='g';

app.ElmovimientoimaginadofueEditFieldLabel.Visible='on';
app.ElmovimientoimaginadofueEditField.Visible='on';
app.ElmovimientoimaginadofueEditField.Value="Pies";

app.Panel_2.Visible='on';
app.Panel_3.Visible='on';
heatmap(app.Panel_2,CSVM3(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión SVM');
heatmap(app.Panel_3,CLDA3(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión LDA');
        end

        % Button pushed function: Movimiento4Button
        function Movimiento4ButtonPushed(app, event)
            global p4
            global Accuracy4
            global TNRT_4
            global TPRT_4
            global Kappa_svm_4
            global AccuracyLDA4
            global TNRTLDA_4
            global TPRTLDA_4
            global Kappa_lda_4
            global CSVM4
            global CLDA4
            global suj2
            
%Apagar 
app.PromediosdetodoslosmovimientosLabel.Visible='off';
app.PrecisionSVM.Visible='off';
app.EspecificidadSVM.Visible='off';
app.SensibilidadSVM.Visible='off';
app.KappaSVM.Visible='off';
app.PrecisionLDA.Visible='off';
app.EspecificidadLDA.Visible='off';
app.SensibilidadLDA.Visible='off';
app.KappaLDA.Visible='off';
app.Panel_2.Visible='off';
app.Panel_3.Visible='off';

app.ElmovimientoimaginadofueEditFieldLabel.Visible='off';
app.ElmovimientoimaginadofueEditField.Visible='off';

%lamparas apagadas
app.Lamp.Color='w';
app.Lamp_2.Color='w';
app.Lamp_3.Color='w';
app.Lamp_4.Color='w';

%inicio codigo
Dx=200;
y1=min(p4(:,1));
y2=max(p4(:,22)+22*80);
for t=1:200:1052
for k=1:22
plot(app.UIAxes3,p4(:,k)+k*80)
hold(app.UIAxes3,"on")
end
app.UIAxes3.XLim=[t t+Dx];
app.UIAxes3.YLim=[y1 y2];
str2 = ['Paciente ',num2str(suj2), ' Señales EEG - Movimiento imaginado lengua'];
title(app.UIAxes3,str2)
drawnow;
end
hold(app.UIAxes3,"off")

app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = Accuracy4(suj2);
app.EspecificidadSVM.Value = TNRT_4(suj2);
app.SensibilidadSVM.Value = TPRT_4(suj2);
app.KappaSVM.Value = Kappa_svm_4(suj2);

app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = AccuracyLDA4(suj2);
app.EspecificidadLDA.Value = TNRTLDA_4(suj2);
app.SensibilidadLDA.Value = TPRTLDA_4(suj2);
app.KappaLDA.Value = Kappa_lda_4(suj2);

app.Lamp_5.Color='g';

app.ElmovimientoimaginadofueEditFieldLabel.Visible='on';
app.ElmovimientoimaginadofueEditField.Visible='on';
app.ElmovimientoimaginadofueEditField.Value="Lengua";

app.Panel_2.Visible='on';
app.Panel_3.Visible='on';
heatmap(app.Panel_2,CSVM4(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión SVM');
heatmap(app.Panel_3,CLDA4(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión LDA');
        end

        % Button pushed function: TodosMovimientosButton
        function TodosMovimientosButtonPushed(app, event)
           global suj2
            global Accuracy
            global TNRT
            global TPRT
            global Kappa_svm
            global AccuracyLDA
            global TNRTLDA
            global TPRTLDA
            global Kappa_lda
            global p1
            global Accuracy2
            global TNRT_2
            global TPRT_2
            global Kappa_svm_2
            global AccuracyLDA2
            global TNRTLDA_2
            global TPRTLDA_2
            global Kappa_lda_2
            global p2
            global Accuracy3
            global TNRT_3
            global TPRT_3
            global Kappa_svm_3
            global AccuracyLDA3
            global TNRTLDA_3
            global TPRTLDA_3
            global Kappa_lda_3
            global p3 
            global p4
            global Accuracy4
            global TNRT_4
            global TPRT_4
            global Kappa_svm_4
            global AccuracyLDA4
            global TNRTLDA_4
            global TPRTLDA_4
            global Kappa_lda_4
            global CSVM
            global CLDA
            global CSVM2
            global CLDA2
            global CSVM3
            global CLDA3
            global CSVM4
            global CLDA4
            global suj 

ATSVM=(sum(Accuracy)/9+sum(Accuracy2)/9+sum(Accuracy3)/9+sum(Accuracy4)/9)/4;
ATLDA=(sum(AccuracyLDA)/9+sum(AccuracyLDA2)/9+sum(AccuracyLDA3)/9+sum(AccuracyLDA4)/9)/4;
ETSVM=(sum(TNRT)/9+sum(TNRT_2)/9+sum(TNRT_3)/9+sum(TNRT_4)/9)/4;
ETLDA=(sum(TNRTLDA)/9+sum(TNRTLDA_2)/9+sum(TNRTLDA_3)/9+sum(TNRTLDA_4)/9)/4;
STSVM=(sum(TPRT)/9+sum(TPRT_2)/9+sum(TPRT_3)/9+sum(TPRT_4)/9)/4;
STLDA=(sum(TPRTLDA)/9+sum(TPRTLDA_2)/9+sum(TPRTLDA_3)/9+sum(TPRTLDA_4)/9)/4;
kTSVM=(sum(Kappa_svm)/9+sum(Kappa_svm_2)/9+sum(Kappa_svm_3)/9+sum(Kappa_svm_4)/9)/4;
kTLDA=(sum(Kappa_lda)/9+sum(Kappa_lda_2)/9+sum(Kappa_lda_3)/9+sum(Kappa_lda_4)/9)/4;

           if isequal (suj,'Todos') 
app.PromediosdetodoslosmovimientosLabel.Visible='on';         
app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = ATSVM;
app.EspecificidadSVM.Value = ETSVM;
app.SensibilidadSVM.Value = STSVM;
app.KappaSVM.Value = kTSVM;

app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = ATLDA;
app.EspecificidadLDA.Value = ETLDA;
app.SensibilidadLDA.Value = STLDA;
app.KappaLDA.Value = kTLDA;
          
           else         
           
%Apagar 
app.PrecisionSVM.Visible='off';
app.EspecificidadSVM.Visible='off';
app.SensibilidadSVM.Visible='off';
app.KappaSVM.Visible='off';
app.PrecisionLDA.Visible='off';
app.EspecificidadLDA.Visible='off';
app.SensibilidadLDA.Visible='off';
app.KappaLDA.Visible='off';
app.Panel_2.Visible='off';
app.Panel_3.Visible='off';
app.ElmovimientoimaginadofueEditFieldLabel.Visible='off';
app.ElmovimientoimaginadofueEditField.Visible='off';

%lamparas apagadas
app.Lamp_2.Color='w';
app.Lamp_3.Color='w';
app.Lamp_4.Color='w';
app.Lamp_5.Color='w';

Dx=200;
y1=min(p1(:,1));
y2=max(p1(:,22)+22*80);
for t=1:200:1052
for k=1:22
plot(app.UIAxes3,p1(:,k)+k*80)
hold(app.UIAxes3,"on")
end
app.UIAxes3.XLim=[t t+Dx];
app.UIAxes3.YLim=[y1 y2];
str2 = ['Paciente ',num2str(suj2), ' Señales EEG - Movimiento imaginado mano izquierda'];
title(app.UIAxes3,str2)
drawnow;
end
hold(app.UIAxes3,"off")

app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = Accuracy(suj2);
app.EspecificidadSVM.Value = TNRT(suj2);
app.SensibilidadSVM.Value = TPRT(suj2);
app.KappaSVM.Value = Kappa_svm(suj2);
app.Lamp.Color='g';

app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = AccuracyLDA(suj2);
app.EspecificidadLDA.Value = TNRTLDA(suj2);
app.SensibilidadLDA.Value = TPRTLDA(suj2);
app.KappaLDA.Value = Kappa_lda(suj2);
app.Lamp.Color='g';
app.ElmovimientoimaginadofueEditFieldLabel.Visible='on';
app.ElmovimientoimaginadofueEditField.Visible='on';
app.ElmovimientoimaginadofueEditField.Value="Mano izquierda";
app.Panel_2.Visible='on';
app.Panel_3.Visible='on';
heatmap(app.Panel_2,CSVM(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión SVM');
heatmap(app.Panel_3,CLDA(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión LDA');
pause(10)

            
%Apagar 
app.PrecisionSVM.Visible='off';
app.EspecificidadSVM.Visible='off';
app.SensibilidadSVM.Visible='off';
app.KappaSVM.Visible='off';
app.PrecisionLDA.Visible='off';
app.EspecificidadLDA.Visible='off';
app.SensibilidadLDA.Visible='off';
app.KappaLDA.Visible='off';
app.Panel_2.Visible='off';
app.Panel_3.Visible='off';

app.ElmovimientoimaginadofueEditFieldLabel.Visible='off';
app.ElmovimientoimaginadofueEditField.Visible='off';

%lamparas apagadas
app.Lamp.Color='w';
app.Lamp_3.Color='w';
app.Lamp_4.Color='w';
app.Lamp_5.Color='w';

%inicio codigo
Dx=200;
y1=min(p2(:,1));
y2=max(p2(:,22)+22*80);
for t=1:200:1052
for k=1:22
plot(app.UIAxes3,p2(:,k)+k*80)
hold(app.UIAxes3,"on")
end
app.UIAxes3.XLim=[t t+Dx];
app.UIAxes3.YLim=[y1 y2];
str2 = ['Paciente ',num2str(suj2), ' Señales EEG - Movimiento imaginado mano derecha'];
title(app.UIAxes3,str2)
drawnow;
end
hold(app.UIAxes3,"off")

app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = Accuracy2(suj2);
app.EspecificidadSVM.Value = TNRT_2(suj2);
app.SensibilidadSVM.Value = TPRT_2(suj2);
app.KappaSVM.Value = Kappa_svm_2(suj2);

app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = AccuracyLDA2(suj2);
app.EspecificidadLDA.Value = TNRTLDA_2(suj2);
app.SensibilidadLDA.Value = TPRTLDA_2(suj2);
app.KappaLDA.Value = Kappa_lda_2(suj2);

app.Lamp_2.Color='g';
app.ElmovimientoimaginadofueEditFieldLabel.Visible='on';
app.ElmovimientoimaginadofueEditField.Visible='on';
app.ElmovimientoimaginadofueEditField.Value="Mano derecha";
app.Panel_2.Visible='on';
app.Panel_3.Visible='on';
heatmap(app.Panel_2,CSVM2(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión SVM');
heatmap(app.Panel_3,CLDA2(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión LDA');
pause(10)
            
%Apagar 
app.PrecisionSVM.Visible='off';
app.EspecificidadSVM.Visible='off';
app.SensibilidadSVM.Visible='off';
app.KappaSVM.Visible='off';
app.PrecisionLDA.Visible='off';
app.EspecificidadLDA.Visible='off';
app.SensibilidadLDA.Visible='off';
app.KappaLDA.Visible='off';
app.Panel_2.Visible='off';
app.Panel_3.Visible='off';

app.ElmovimientoimaginadofueEditFieldLabel.Visible='off';
app.ElmovimientoimaginadofueEditField.Visible='off';

%lamparas apagadas
app.Lamp.Color='w';
app.Lamp_2.Color='w';
app.Lamp_5.Color='w';

%inicio codigo
Dx=200;
y1=min(p3(:,1));
y2=max(p3(:,22)+22*80);
for t=1:200:1052
for k=1:22
plot(app.UIAxes3,p3(:,k)+k*80)
hold(app.UIAxes3,"on")
end
app.UIAxes3.XLim=[t t+Dx];
app.UIAxes3.YLim=[y1 y2];
str2 = ['Paciente ',num2str(suj2), ' Señales EEG - Movimiento imaginado pies'];
title(app.UIAxes3,str2)
drawnow;
end
hold(app.UIAxes3,"off")

app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = Accuracy3(suj2);
app.EspecificidadSVM.Value = TNRT_3(suj2);
app.SensibilidadSVM.Value = TPRT_3(suj2);
app.KappaSVM.Value = Kappa_svm_3(suj2);

app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = AccuracyLDA3(suj2);
app.EspecificidadLDA.Value = TNRTLDA_3(suj2);
app.SensibilidadLDA.Value = TPRTLDA_3(suj2);
app.KappaLDA.Value = Kappa_lda_3(suj2);

app.Lamp_3.Color='g';
app.Lamp_4.Color='g';

app.ElmovimientoimaginadofueEditFieldLabel.Visible='on';
app.ElmovimientoimaginadofueEditField.Visible='on';
app.ElmovimientoimaginadofueEditField.Value="Pies";
app.Panel_2.Visible='on';
app.Panel_3.Visible='on';
heatmap(app.Panel_2,CSVM3(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión SVM');
heatmap(app.Panel_3,CLDA3(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión LDA');
pause(10)
            
%Apagar 
app.PrecisionSVM.Visible='off';
app.EspecificidadSVM.Visible='off';
app.SensibilidadSVM.Visible='off';
app.KappaSVM.Visible='off';
app.PrecisionLDA.Visible='off';
app.EspecificidadLDA.Visible='off';
app.SensibilidadLDA.Visible='off';
app.KappaLDA.Visible='off';
app.Panel_2.Visible='off';
app.Panel_3.Visible='off';
app.ElmovimientoimaginadofueEditFieldLabel.Visible='off';
app.ElmovimientoimaginadofueEditField.Visible='off';

%lamparas apagadas
app.Lamp.Color='w';
app.Lamp_2.Color='w';
app.Lamp_3.Color='w';
app.Lamp_4.Color='w';

%inicio codigo
Dx=200;
y1=min(p4(:,1));
y2=max(p4(:,22)+22*80);
for t=1:200:1052
for k=1:22
plot(app.UIAxes3,p4(:,k)+k*80)
hold(app.UIAxes3,"on")
end
app.UIAxes3.XLim=[t t+Dx];
app.UIAxes3.YLim=[y1 y2];
str2 = ['Paciente ',num2str(suj2), ' Señales EEG - Movimiento imaginado lengua'];
title(app.UIAxes3,str2)
drawnow;
end
hold(app.UIAxes3,"off")

app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = Accuracy4(suj2);
app.EspecificidadSVM.Value = TNRT_4(suj2);
app.SensibilidadSVM.Value = TPRT_4(suj2);
app.KappaSVM.Value = Kappa_svm_4(suj2);

app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = AccuracyLDA4(suj2);
app.EspecificidadLDA.Value = TNRTLDA_4(suj2);
app.SensibilidadLDA.Value = TPRTLDA_4(suj2);
app.KappaLDA.Value = Kappa_lda_4(suj2);

app.Lamp_5.Color='g';

app.ElmovimientoimaginadofueEditFieldLabel.Visible='on';
app.ElmovimientoimaginadofueEditField.Visible='on';
app.ElmovimientoimaginadofueEditField.Value="Lengua";
app.Panel_2.Visible='on';
app.Panel_3.Visible='on';
heatmap(app.Panel_2,CSVM4(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión SVM');
heatmap(app.Panel_3,CLDA4(:,:,suj2),"XLabel",'Clase Predicha',"YLabel",...
    'Clase Verdadera',"Title",'Matriz de confusión LDA');
pause(10)

%Promedios 
app.PromediosdetodoslosmovimientosLabel.Visible='on';
app.PrecisionSVM.Visible='on';
app.EspecificidadSVM.Visible='on';
app.SensibilidadSVM.Visible='on';
app.KappaSVM.Visible='on';
app.PrecisionSVM.Value = ATSVM;
app.EspecificidadSVM.Value = ETSVM;
app.SensibilidadSVM.Value = STSVM;
app.KappaSVM.Value = kTSVM;

app.PrecisionLDA.Visible='on';
app.EspecificidadLDA.Visible='on';
app.SensibilidadLDA.Visible='on';
app.KappaLDA.Visible='on';
app.PrecisionLDA.Value = ATLDA;
app.EspecificidadLDA.Value = ETLDA;
app.SensibilidadLDA.Value = STLDA;
app.KappaLDA.Value = kTLDA;
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ElmovimientoimaginadofueEditFieldLabel = uilabel(app.UIFigure);
            app.ElmovimientoimaginadofueEditFieldLabel.Visible = 'off';
            app.ElmovimientoimaginadofueEditFieldLabel.Text = 'El movimiento imaginado fue ';
            app.ElmovimientoimaginadofueEditField = uieditfield(app.UIFigure, 'text');
            app.ElmovimientoimaginadofueEditField.Editable = 'off';
            app.ElmovimientoimaginadofueEditField.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Movimiento3Button = uibutton(app.Panel, 'push');
            app.Movimiento3Button.ButtonPushedFcn = createCallbackFcn(app, @Movimiento3ButtonPushed, true);
            app.Movimiento3Button.Text = 'Movimiento 3';
            app.Movimiento4Button = uibutton(app.Panel, 'push');
            app.Movimiento4Button.ButtonPushedFcn = createCallbackFcn(app, @Movimiento4ButtonPushed, true);
            app.Movimiento4Button.Text = 'Movimiento 4';
            app.SujetoDropDownLabel = uilabel(app.Panel);
            app.SujetoDropDownLabel.Text = 'Sujeto';
            app.SujetoDropDown = uidropdown(app.Panel);
            app.SujetoDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', 'Todos'};
            app.SujetoDropDown.ValueChangedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.SujetoDropDown.Value = '1';
            app.Movimiento1Button = uibutton(app.Panel, 'push');
            app.Movimiento1Button.ButtonPushedFcn = createCallbackFcn(app, @Movimiento1ButtonPushed, true);
            app.Movimiento1Button.Text = 'Movimiento 1';
            app.Movimiento2Button = uibutton(app.Panel, 'push');
            app.Movimiento2Button.ButtonPushedFcn = createCallbackFcn(app, @Movimiento2ButtonPushed, true);
            app.Movimiento2Button.Text = 'Movimiento 2';
            app.UIAxes3 = uiaxes(app.Panel);
            title(app.UIAxes3, 'Señal EEG')
            xlabel(app.UIAxes3, 'Tiempo')
            ylabel(app.UIAxes3, 'Voltaje')
            app.TodosMovimientosButton = uibutton(app.Panel, 'push');
            app.TodosMovimientosButton.ButtonPushedFcn = createCallbackFcn(app, @TodosMovimientosButtonPushed, true);
            app.TodosMovimientosButton.Text = 'Todos Movimientos';
            app.Paracadaunodelosmovimientossetienen72repeticionesLabel = uilabel(app.Panel);
            app.Paracadaunodelosmovimientossetienen72repeticionesLabel.Text = 'Para cada uno de los movimientos, se tienen 72 repeticiones. ';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.LinearDiscriminantAnalysisPanel = uipanel(app.UIFigure);
            app.LinearDiscriminantAnalysisPanel.Title = 'Linear Discriminant Analysis';
            app.PrecisionLDA = uieditfield(app.LinearDiscriminantAnalysisPanel, 'numeric');
            app.PrecisionLDA.ValueChangedFcn = createCallbackFcn(app, @Movimiento1ButtonPushed, true);
            app.PrecisionLDA.Visible = 'off';
            app.EspecificidadLDA = uieditfield(app.LinearDiscriminantAnalysisPanel, 'numeric');
            app.EspecificidadLDA.ValueChangedFcn = createCallbackFcn(app, @Movimiento1ButtonPushed, true);
            app.EspecificidadLDA.Visible = 'off';
            app.SensibilidadLDA = uieditfield(app.LinearDiscriminantAnalysisPanel, 'numeric');
            app.SensibilidadLDA.ValueChangedFcn = createCallbackFcn(app, @Movimiento1ButtonPushed, true);
            app.SensibilidadLDA.Visible = 'off';
            app.KappaLDA = uieditfield(app.LinearDiscriminantAnalysisPanel, 'numeric');
            app.KappaLDA.ValueChangedFcn = createCallbackFcn(app, @Movimiento1ButtonPushed, true);
            app.KappaLDA.Visible = 'off';
            app.PrecisionLabel_2 = uilabel(app.LinearDiscriminantAnalysisPanel);
            app.PrecisionLabel_2.Text = 'Precision';
            app.EspecificidadLabel_2 = uilabel(app.LinearDiscriminantAnalysisPanel);
            app.EspecificidadLabel_2.Text = 'Especificidad';
            app.SensibilidadLabel_2 = uilabel(app.LinearDiscriminantAnalysisPanel);
            app.SensibilidadLabel_2.Text = 'Sensibilidad';
            app.KappaLabel_2 = uilabel(app.LinearDiscriminantAnalysisPanel);
            app.KappaLabel_2.Text = 'Kappa';
            app.Panel_3 = uipanel(app.LinearDiscriminantAnalysisPanel);
            app.SupportMachineVectorPanel = uipanel(app.UIFigure);
            app.SupportMachineVectorPanel.Title = 'Support Machine Vector';
            app.PrecisionSVM = uieditfield(app.SupportMachineVectorPanel, 'numeric');
            app.PrecisionSVM.ValueChangedFcn = createCallbackFcn(app, @Movimiento1ButtonPushed, true);
            app.PrecisionSVM.Editable = 'off';
            app.PrecisionSVM.Visible = 'off';
            app.EspecificidadSVM = uieditfield(app.SupportMachineVectorPanel, 'numeric');
            app.EspecificidadSVM.ValueChangedFcn = createCallbackFcn(app, @Movimiento1ButtonPushed, true);
            app.EspecificidadSVM.Editable = 'off';
            app.EspecificidadSVM.Visible = 'off';
            app.PrecisionLabel = uilabel(app.SupportMachineVectorPanel);
            app.PrecisionLabel.Text = 'Precision';
            app.EspecificidadLabel = uilabel(app.SupportMachineVectorPanel);
            app.EspecificidadLabel.Text = 'Especificidad';
            app.SensibilidadLabel = uilabel(app.SupportMachineVectorPanel);
            app.SensibilidadLabel.Text = 'Sensibilidad';
            app.SensibilidadSVM = uieditfield(app.SupportMachineVectorPanel, 'numeric');
            app.SensibilidadSVM.ValueChangedFcn = createCallbackFcn(app, @Movimiento1ButtonPushed, true);
            app.SensibilidadSVM.Editable = 'off';
            app.SensibilidadSVM.Visible = 'off';
            app.KappaLabel = uilabel(app.SupportMachineVectorPanel);
            app.KappaLabel.Text = 'Kappa';
            app.KappaSVM = uieditfield(app.SupportMachineVectorPanel, 'numeric');
            app.KappaSVM.ValueChangedFcn = createCallbackFcn(app, @Movimiento1ButtonPushed, true);
            app.KappaSVM.Editable = 'off';
            app.KappaSVM.Visible = 'off';
            app.Panel_2 = uipanel(app.SupportMachineVectorPanel);
            app.YapuedevisualizarLabel = uilabel(app.UIFigure);
            app.YapuedevisualizarLabel.Visible = 'off';
            app.YapuedevisualizarLabel.Text = 'Ya puede visualizar';
            app.PromediosdetodoslosmovimientosLabel = uilabel(app.UIFigure);
            app.PromediosdetodoslosmovimientosLabel.Visible = 'off';
            app.PromediosdetodoslosmovimientosLabel.Text = {'Promedios de todos'; ' los movimientos'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end